classdef controls_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ShoulderLabel              matlab.ui.control.Label
        RotateLeftButton           matlab.ui.control.Button
        RotateRightButton          matlab.ui.control.Button
        ClosegripperButton         matlab.ui.control.Button
        OpengripperButton          matlab.ui.control.Button
        BaseSlider                 matlab.ui.control.Slider
        BaseSliderLabel            matlab.ui.control.Label
        WristRotateSlider          matlab.ui.control.Slider
        ShoulderSlider_2Label_2    matlab.ui.control.Label
        ShoulderSlider             matlab.ui.control.Slider
        ShoulderSlider_2Label      matlab.ui.control.Label
        ElbowSlider_2              matlab.ui.control.Slider
        ElbowSlider_2Label         matlab.ui.control.Label
        WristPitchSlider           matlab.ui.control.Slider
        WristPitchSliderLabel      matlab.ui.control.Label
        ConnectionstatusLamp       matlab.ui.control.Lamp
        ConnectionstatusLampLabel  matlab.ui.control.Label
        BrakeButton                matlab.ui.control.Button
        EmergencyStopButton        matlab.ui.control.Button
        RightButton                matlab.ui.control.Button
        LeftButton                 matlab.ui.control.Button
        BackwardButton             matlab.ui.control.Button
        ForwardButton              matlab.ui.control.Button
        VelocitySlider             matlab.ui.control.Slider
        VelocitySliderLabel        matlab.ui.control.Label
        Button                     matlab.ui.control.Button
        forwardVisionAxes          matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = public)
        sim            % remoteApi object
        clientID       % Connection ID
        jointHandles   % Struct() storing the joint handles
        visionSensorHandles % Struct() storing the vision sensor handles
        movementSpeed  double = 0 % This will store the slider value
        visionTimer
        sliderTimer
    end
    
    methods (Access = private)
        function getJointHandles(app, jointNames)
            for i = 1:numel(jointNames)
                % Get the joint name
                jointName = jointNames{i};
        
                % Get the joint name from CoppeliaSim
                [~, handle] = app.sim.simxGetObjectHandle(app.clientID, ...
                    jointName, ...
                    app.sim.simx_opmode_oneshot_wait);
                
                 % Store the handle in app.jointHandles struct() using the 
                 % joint name as the field name
                app.jointHandles.(jointName) = handle;
            end
        end

        function updateUISliders(app)
            % A struct() with the joint names and their limits taken from
            % CopelliaSim
            jointsWithLimits = struct ( ...
                'youBotArmJoint0', [-169, 338], ...
                'youBotArmJoint1', [-90, 165], ...
                'youBotArmJoint2', [-131, 262], ...
                'youBotArmJoint3', [-102, 204], ...
                'youBotArmJoint4', [-90, 180] ...
                );

            jointNames = fieldnames(jointsWithLimits);

            % If the joint has predefined limits then configure them
            % into the UI components
            for i = 1:numel(jointNames)
                jointName = jointNames{i};
                limits = jointsWithLimits.(jointName);
                
                % Get the joint handle
                handle = app.jointHandles.(jointName);

                % Get the current position of the join from CoppeliaSim in
                % radians
                [res, currentPosRadians] = app.sim.simxGetJointPosition(app.clientID, ...
                    handle, ...
                    app.sim.simx_opmode_blocking);
    
                if res ~= 0 || isnan(currentPosRadians) || isinf(currentPosRadians)
                    fprintf('Warning: joint %s returned invalid data (res=%d).\n', jointName, res);
                    continue;
                end

                % Convert the value from radians to degrees
                currentPosDegrees = double(rad2deg(currentPosRadians));

                % Extract min and max value limits from the jointsWithLimits
                % struct() arrays
                minVal = limits(1);
                maxVal = limits(2);
                clampedPos = max(min(currentPosDegrees, maxVal), minVal);

                fprintf('Joint: %s, rad=%.2f, deg=%.2f, min=%.2f, max=%.2f, clamped=%.2f\n',...
                jointName, currentPosRadians, currentPosDegrees, minVal, maxVal, clampedPos);

                if strcmp(jointName, 'youBotArmJoint0') && isprop(app, 'BaseSlider')
                    app.BaseSlider.Limits = [minVal, maxVal];
                    app.BaseSlider.Value = clampedPos;
    
                elseif strcmp(jointName, 'youBotArmJoint1') && isprop(app, 'ShoulderSlider')
                    app.ShoulderSlider.Limits = [minVal, maxVal];
                    app.ShoulderSlider.Value = clampedPos;
    
                elseif strcmp(jointName, 'youBotArmJoint2') && isprop(app, 'ElbowSlider_2')
                    app.ElbowSlider_2.Limits = [minVal, maxVal];
                    app.ElbowSlider_2.Value = clampedPos;
    
                elseif strcmp(jointName, 'youBotArmJoint3') && isprop(app, 'WristPitchSlider')
                    app.WristPitchSlider.Limits = [minVal, maxVal];
                    app.WristPitchSlider.Value = clampedPos;
    
                elseif strcmp(jointName, 'youBotArmJoint4') && isprop(app, 'WristRotateSlider')
                    app.WristRotateSlider.Limits = [minVal, maxVal];
                    app.WristRotateSlider.Value = clampedPos;
                end

            end   
        end

        function moveBotForwardBackward(app, velocity)
            % Set the velocity for the right rear rolling joint
            app.sim.simxSetJointTargetVelocity(app.clientID, ...
            app.jointHandles.rollingJoint_rr, ...
            velocity, ...
            app.sim.simx_opmode_streaming);
        
            % Set the velocity for the left rear rolling joint
            app.sim.simxSetJointTargetVelocity(app.clientID, ...
            app.jointHandles.rollingJoint_rl, ...
            velocity, ...
            app.sim.simx_opmode_streaming);
        
            % Set the velocity for the right front rolling joint
            app.sim.simxSetJointTargetVelocity(app.clientID, ...
            app.jointHandles.rollingJoint_fr, ...
            velocity, ...
            app.sim.simx_opmode_streaming);
        
            % Set the velocity for the left front rolling joint
            app.sim.simxSetJointTargetVelocity(app.clientID, ...
            app.jointHandles.rollingJoint_fl, ...
            velocity, ...
            app.sim.simx_opmode_streaming);
    
            fprintf('Robot moving at speed %d\n', velocity);
        end

        function moveSideways(app, velocity)
            % Set the velocity for the front rear rolling joint
            app.sim.simxSetJointTargetVelocity(app.clientID, ...
                app.jointHandles.rollingJoint_fr, ...
                -velocity, ...
                app.sim.simx_opmode_streaming);

            app.sim.simxSetJointTargetVelocity(app.clientID, ...
                app.jointHandles.rollingJoint_fl, ...
                velocity, ...
                app.sim.simx_opmode_streaming);

            app.sim.simxSetJointTargetVelocity(app.clientID, ...
                app.jointHandles.rollingJoint_rr, ...
                velocity, ...
                app.sim.simx_opmode_streaming);

            app.sim.simxSetJointTargetVelocity(app.clientID, ...
                app.jointHandles.rollingJoint_rl, ...
                -velocity, ...
                app.sim.simx_opmode_streaming);

    
            fprintf('Robot moving sideways at speed %d\n', velocity);
        end

        function handleArmJoint(app, jointHandle, angle)
            app.sim.simxSetJointTargetPosition(app.clientID, ...
                jointHandle, ...
                angle, ...
                app.sim.simx_opmode_streaming);
            
            fprintf('Joint moved to %.2f radians\n', angle);
        end

        function smoothSetJointTargetPosition(app, jointHandle, targetPos)
            % Get the current position (blocking call)
            [~, currentPos] = app.sim.simxGetJointPosition(app.clientID, jointHandle, app.sim.simx_opmode_blocking);
            
            % Define smoothing parameters:
            numSteps = 50;         % Number of incremental steps
            dt = 0.02;             % Pause time between steps (seconds)
            stepSize = (targetPos - currentPos) / numSteps;
            
            for i = 1:numSteps
                newPos = currentPos + i * stepSize;
                app.sim.simxSetJointTargetPosition(app.clientID, jointHandle, newPos, app.sim.simx_opmode_oneshot);
                pause(dt);
            end
        end

        function updateVision(app)
            % Ensure client is connected and vision sensor handle is valid
            if app.clientID < 0 || isempty(app.visionSensorHandles.forwardVisionSensor)
                disp('Vision sensor handle is invalid or not initialized.');
                return;
            end
        
            % Retrieve the latest streamed image from CoppeliaSim
            [res, ~, image] = app.sim.simxGetVisionSensorImage2(app.clientID, ...
                app.visionSensorHandles.forwardVisionSensor, ...
                0, ...  % Get RGB image
                app.sim.simx_opmode_buffer); % Fetch latest image
        
            % Check if the image retrieval was successful
            if res ~= 0
                disp(['Failed to retrieve vision sensor image. Error code: ', num2str(res)]);
                return;
            end
        
            % Display the image inside forwardVisionAxes
            if isvalid(app.forwardVisionAxes)
                imshow(image, 'Parent', app.forwardVisionAxes);
                drawnow;
            else
                disp('UIAxes is invalid or deleted.');
            end
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.sim = remApi('remoteApi');
            % To close any existing connections
            app.sim.simxFinish(-1);
            app.clientID = app.sim.simxStart('127.0.0.1', 19000, true, true, 5000, 5);
    
            % Initialize a struct() to store the KUKA YouBot joints
            app.jointHandles = struct();
            
            % A definition of all joint names
            jointNames = { ...
                'rollingJoint_rr', 'rollingJoint_rl', 'rollingJoint_fr', 'rollingJoint_fl', ...
                'slippingJoint_rr', 'slippingJoint_rl', 'joint', 'slippingJoint_fl', 'slippingJoint_fr', ...
                'youBotArmJoint0', 'youBotArmJoint1', 'youBotArmJoint2', ...
                'youBotArmJoint3', 'youBotArmJoint4', 'youBotGripperJoint1', 'youBotGripperJoint2' ...
            };
            
            app.visionSensorHandles = struct();
    
            if app.clientID > -1
                disp('Connected to CoppeliaSim');
                
                getJointHandles(app, jointNames);

                % Front vision sensor
                [~, app.visionSensorHandles.forwardVisionSensor] = app.sim.simxGetObjectHandle(app.clientID, ...
                    'visionSensor', ...
                    app.sim.simx_opmode_oneshot_wait);
          
                [~, ~, ~] = app.sim.simxGetVisionSensorImage2(app.clientID, ...
                    app.visionSensorHandles.forwardVisionSensor, ...
                    0, ...
                    app.sim.simx_opmode_streaming); % Start streaming mode

                app.visionTimer = timer('ExecutionMode', ...
                'fixedSpacing', ...
                'Period', 0.5, ... % Image updates every 0.5 seconds
                'TimerFcn', @(~,~) updateVision(app));

                app.sliderTimer = timer(...
                'ExecutionMode', 'fixedSpacing', ...
                'Period', 1, ... % update every second (adjust as needed)
                'TimerFcn', @(~,~) updateUISliders(app)); % Update the UI slider components with the correct values
                
            
                start(app.visionTimer);
                start(app.sliderTimer);

                % Lamp is green if there is a connection
                app.ConnectionstatusLamp.Color = [0 1 0];                
            else
                disp('Connection failed');

                % Lamp is red if there is a no connection
                app.ConnectionstatusLamp.Color = [1 0 0];
            end
        end

        % Value changed function: VelocitySlider
        function VelocitySliderValueChanged(app, event)
            app.movementSpeed = app.VelocitySlider.Value;
            disp(['Slider value set to: ', num2str(app.movementSpeed)]);
            
        end

        % Callback function: ForwardButton, UIFigure
        function moveBotForward(app, event)
            app.moveBotForwardBackward(app.movementSpeed);
        end

        % Button pushed function: BackwardButton
        function moveBotBackward(app, event)
            app.moveBotForwardBackward(-app.movementSpeed);
        end

        % Button pushed function: RightButton
        function moveBotRight(app, event)
            app.moveSideways(app.movementSpeed);
        end

        % Button pushed function: LeftButton
        function LeftButtonPushed(app, event)
            app.moveSideways(-app.movementSpeed);
        end

        % Button pushed function: EmergencyStopButton
        function EmergencyStop(app, event)
            app.moveBotForwardBackward(0);
        end

        % Button pushed function: BrakeButton
        function Brake(app, event)
            desiredVelocity = 0; % Target velocity (fully stopped)
            currentVelocity = app.movementSpeed; % Initial velocity
    
            Kp = 0.5; % Proportional gain (adjust as needed)
    
            % Preserve the direction of motion (sign of the velocity)
            direction = sign(currentVelocity); % +1 (forward), -1 (backward), etc.
    
            while abs(currentVelocity - desiredVelocity) > 0.05
                error = desiredVelocity - currentVelocity;
                controlSignal = Kp * error; % Apply proportional control
                
                % Ensure braking maintains the correct movement direction
                currentVelocity = currentVelocity + direction * controlSignal; 
    
                % Apply the adjusted velocity to all wheels
                app.sim.simxSetJointTargetVelocity(app.clientID, app.jointHandles.rollingJoint_rr, currentVelocity, app.sim.simx_opmode_streaming);
                app.sim.simxSetJointTargetVelocity(app.clientID, app.jointHandles.rollingJoint_rl, currentVelocity, app.sim.simx_opmode_streaming);
                app.sim.simxSetJointTargetVelocity(app.clientID, app.jointHandles.rollingJoint_fr, currentVelocity, app.sim.simx_opmode_streaming);
                app.sim.simxSetJointTargetVelocity(app.clientID, app.jointHandles.rollingJoint_fl, currentVelocity, app.sim.simx_opmode_streaming);
    
                pause(0.1);
            end
        end

        % Value changed function: BaseSlider
        function BaseSliderValueChanged(app, event)
            value = app.BaseSlider.Value;
            radValue = deg2rad(value); 
            app.handleArmJoint(app.jointHandles.youBotArmJoint0, radValue);
        end

        % Value changed function: ShoulderSlider
        function ShoulderSliderValueChanged(app, event)
            value = app.ShoulderSlider.Value;
            radValue = deg2rad(value); 
            app.handleArmJoint(app.jointHandles.youBotArmJoint1, radValue);
        end

        % Value changed function: ElbowSlider_2
        function ElbowSlider_2ValueChanged(app, event)
            value = app.ElbowSlider_2.Value;
            radValue = deg2rad(value); 
            app.handleArmJoint(app.jointHandles.youBotArmJoint2, radValue);
        end

        % Value changed function: WristPitchSlider
        function WristPitchSliderValueChanged(app, event)
            value = app.WristPitchSlider.Value;
            radValue = deg2rad(value); 
            app.handleArmJoint(app.jointHandles.youBotArmJoint3, radValue);
        end

        % Value changed function: WristRotateSlider
        function WristRotateSliderValueChanged(app, event)
            value = app.WristRotateSlider.Value;
            radValue = deg2rad(value); 
            app.handleArmJoint(app.jointHandles.youBotArmJoint4, radValue);
        end

        % Button pushed function: OpengripperButton
        function OpengripperButtonPushed(app, event)
            % Desired target positions for opening
            joint1Pos = 0.04;
            joint2Pos = -0.04; % For youBotGripperJoint2
        
            % Smoothly move each joint to its target
            smoothSetJointTargetPosition(app, ...
                app.jointHandles.youBotGripperJoint1, ...
                joint1Pos);

            smoothSetJointTargetPosition(app, ...
                app.jointHandles.youBotGripperJoint2, ...
                joint2Pos);
            disp('Gripper Opened Smoothly.');
        end

        % Button pushed function: ClosegripperButton
        function ClosegripperButtonPushed(app, event)
            % Desired target positions for opening
            joint1Pos = 0;
            joint2Pos = 0; % For youBotGripperJoint2
        
            % Smoothly move each joint to its target
            smoothSetJointTargetPosition(app, ...
                app.jointHandles.youBotGripperJoint1, ...
                joint1Pos);

            smoothSetJointTargetPosition(app, ...
                app.jointHandles.youBotGripperJoint2, ...
                joint2Pos);
            disp('Gripper Opened Smoothly.');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @moveBotForward, true);
            app.forwardVisionAxes = uiaxes(app.UIFigure);
            title(app.forwardVisionAxes, 'Front Facing Camera')
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.Text = '🔼';
            app.VelocitySliderLabel = uilabel(app.UIFigure);
            app.VelocitySliderLabel.Text = 'Velocity';
            app.VelocitySlider = uislider(app.UIFigure);
            app.VelocitySlider.Limits = [0 5];
            app.VelocitySlider.ValueChangedFcn = createCallbackFcn(app, @VelocitySliderValueChanged, true);
            app.VelocitySlider.MinorTicks = [0 1 2 3 4 5];
            app.ForwardButton = uibutton(app.UIFigure, 'push');
            app.ForwardButton.ButtonPushedFcn = createCallbackFcn(app, @moveBotForward, true);
            app.ForwardButton.Text = 'Forward';
            app.BackwardButton = uibutton(app.UIFigure, 'push');
            app.BackwardButton.ButtonPushedFcn = createCallbackFcn(app, @moveBotBackward, true);
            app.BackwardButton.Text = 'Backward';
            app.LeftButton = uibutton(app.UIFigure, 'push');
            app.LeftButton.ButtonPushedFcn = createCallbackFcn(app, @LeftButtonPushed, true);
            app.LeftButton.Text = 'Left';
            app.RightButton = uibutton(app.UIFigure, 'push');
            app.RightButton.ButtonPushedFcn = createCallbackFcn(app, @moveBotRight, true);
            app.RightButton.Text = 'Right';
            app.EmergencyStopButton = uibutton(app.UIFigure, 'push');
            app.EmergencyStopButton.ButtonPushedFcn = createCallbackFcn(app, @EmergencyStop, true);
            app.EmergencyStopButton.Text = 'Emergency Stop';
            app.BrakeButton = uibutton(app.UIFigure, 'push');
            app.BrakeButton.ButtonPushedFcn = createCallbackFcn(app, @Brake, true);
            app.BrakeButton.Text = 'Brake';
            app.ConnectionstatusLampLabel = uilabel(app.UIFigure);
            app.ConnectionstatusLampLabel.Text = 'Connection status';
            app.ConnectionstatusLamp = uilamp(app.UIFigure);
            app.WristPitchSliderLabel = uilabel(app.UIFigure);
            app.WristPitchSliderLabel.Text = 'Wrist Pitch';
            app.WristPitchSlider = uislider(app.UIFigure);
            app.WristPitchSlider.Limits = [-102 204];
            app.WristPitchSlider.ValueChangedFcn = createCallbackFcn(app, @WristPitchSliderValueChanged, true);
            app.ElbowSlider_2Label = uilabel(app.UIFigure);
            app.ElbowSlider_2Label.Text = 'Elbow';
            app.ElbowSlider_2 = uislider(app.UIFigure);
            app.ElbowSlider_2.Limits = [-131 262];
            app.ElbowSlider_2.ValueChangedFcn = createCallbackFcn(app, @ElbowSlider_2ValueChanged, true);
            app.ShoulderSlider_2Label = uilabel(app.UIFigure);
            app.ShoulderSlider_2Label.Text = 'Shoulder';
            app.ShoulderSlider = uislider(app.UIFigure);
            app.ShoulderSlider.Limits = [-90 165];
            app.ShoulderSlider.ValueChangedFcn = createCallbackFcn(app, @ShoulderSliderValueChanged, true);
            app.ShoulderSlider.Value = 32.465;
            app.ShoulderSlider_2Label_2 = uilabel(app.UIFigure);
            app.ShoulderSlider_2Label_2.Text = 'Wrist Rotate';
            app.WristRotateSlider = uislider(app.UIFigure);
            app.WristRotateSlider.Limits = [-90 180];
            app.WristRotateSlider.ValueChangedFcn = createCallbackFcn(app, @WristRotateSliderValueChanged, true);
            app.BaseSliderLabel = uilabel(app.UIFigure);
            app.BaseSliderLabel.Text = 'Base';
            app.BaseSlider = uislider(app.UIFigure);
            app.BaseSlider.Limits = [-999 999];
            app.BaseSlider.ValueChangedFcn = createCallbackFcn(app, @BaseSliderValueChanged, true);
            app.OpengripperButton = uibutton(app.UIFigure, 'push');
            app.OpengripperButton.ButtonPushedFcn = createCallbackFcn(app, @OpengripperButtonPushed, true);
            app.OpengripperButton.Text = 'Open gripper';
            app.ClosegripperButton = uibutton(app.UIFigure, 'push');
            app.ClosegripperButton.ButtonPushedFcn = createCallbackFcn(app, @ClosegripperButtonPushed, true);
            app.ClosegripperButton.Text = 'Close gripper';
            app.RotateRightButton = uibutton(app.UIFigure, 'push');
            app.RotateRightButton.Text = 'Rotate Right';
            app.RotateLeftButton = uibutton(app.UIFigure, 'push');
            app.RotateLeftButton.Text = 'Rotate Left';
            app.ShoulderLabel = uilabel(app.UIFigure);
            app.ShoulderLabel.Text = 'Shoulder';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = controls_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end